classdef MyApp_P < matlab.apps.AppBase
    %MYAPP_P Used to plot polynomials
    %   DRAW - draw plot of polynomial between Vmin and Vmax 
    %   
    %   Options - choose way plot is draw:
    %   Linear, Logarythmic, SemiLogarythmicX, SemiLogarythmicY
    %   
    %   GRID ON/OFF - put grid on/off on plot
    %   
    %   CALCULATE DERIATIVE - calculate deriative of polynomial in choosen
    %   order
    %   
    %   

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        CALCULATEDERIVATIVEButton      matlab.ui.control.Button
        DerivativeorderEditField       matlab.ui.control.EditField
        DerivativeorderEditFieldLabel  matlab.ui.control.Label
        GRIDONOFFButton                matlab.ui.control.Button
        HELPButton                     matlab.ui.control.Button
        DRAWButton                     matlab.ui.control.Button
        VmaxEditField                  matlab.ui.control.EditField
        VmaxEditFieldLabel             matlab.ui.control.Label
        VminEditField                  matlab.ui.control.EditField
        VminEditFieldLabel             matlab.ui.control.Label
        PolynomialEditFieldLabel       matlab.ui.control.Label
        PolynomialEditField            matlab.ui.control.EditField
        AuthorWojciechZubLabel         matlab.ui.control.Label
        OptionsButtonGroup             matlab.ui.container.ButtonGroup
        SemiLogarythmicYButton         matlab.ui.control.RadioButton
        SemiLogarythmicXButton         matlab.ui.control.RadioButton
        LogarythmicButton              matlab.ui.control.RadioButton
        LinearButton                   matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        val_G = 1;
        val_O = 1;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DRAWButton
        function ploting(app, event)
            Vmin=str2num(app.VminEditField.Value);
            Vmax=str2num(app.VmaxEditField.Value);
            P=str2num(app.PolynomialEditField.Value);
            plot(app.UIAxes,Vmin:Vmax,polyval(P,Vmin:Vmax));
            if app.val_O == 1
                 app.UIAxes.YScale = 'linear';
                 app.UIAxes.XScale = 'linear';
                 title(app.UIAxes,'Diagram Linear');
            elseif app.val_O == 2
                 app.UIAxes.YScale = 'log';
                 app.UIAxes.XScale = 'log';
                 title(app.UIAxes,'Diagram Log');
            elseif app.val_O == 3
                 app.UIAxes.YScale = 'linear';
                 app.UIAxes.XScale = 'log';
                 title(app.UIAxes,'Diagram SemiLogX');
            elseif app.val_O == 4
                 app.UIAxes.YScale = 'log';
                 app.UIAxes.XScale = 'linear';
                 title(app.UIAxes,'Diagram SemiLogY');
            end
          
          
            xlabel(app.UIAxes,'X - arguments');
            ylabel(app.UIAxes,'Y - values');  
        end

        % Button pushed function: GRIDONOFFButton
        function GRIDONOFFButtonPushed(app, event)

            if app.val_G == 0
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
                app.val_G =1;
            else
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';  
                app.val_G =0;
            end
 
        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            fh = figure('Name', 'HELP info');
            help MyApp_P

            text = "MYAPP_P - Use to plot polynomials and calculate Derivative";
            uicontrol('Style','text','String',text,'FontSize',15,'Units','normalized','Position',[0,0.9,1,0.1]);
            
            text2 = "DRAW - draw plot of polynomial between Vmin and Vmax ";
            text2 = text2 + newline+ " ";
            text2 = text2 + newline+ "Options - choose way plot is draw ";
            text2 = text2 + newline+ "Linear, Logarythmic, SemiLogarythmicX, SemiLogarythmicY";
            text2 = text2 + newline+ " ";
            text2 = text2 + newline+ "GRID ON/OFF - put grid on/off on plot";
            text2 = text2 + newline+ " ";
            text2 = text2 + newline+ "CALCULATE DERIATIVE - calculate derivative of polynomial in choosen order";


             uicontrol('Style','text','String',text2,'HorizontalAlignment','left','FontSize',9,'Units','normalized','Position',[0,0,1,0.9]);
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            if app.LinearButton.Value == 1
                app.val_O = 1;
            elseif app.LogarythmicButton.Value == 1
                app.val_O = 2; 
            elseif app.SemiLogarythmicXButton.Value == 1
                app.val_O = 3;
            elseif app.SemiLogarythmicYButton.Value == 1
                app.val_O = 4;
            end

        end

        % Button pushed function: CALCULATEDERIVATIVEButton
        function CALCULATEDERIVATIVEButtonPushed(app, event)
            P=str2num(app.PolynomialEditField.Value);
            d=str2num(app.DerivativeorderEditField.Value);
            D = P;

            for i=1:1:d 
                D = polyder(D); 
            end

            app.Label.Text=num2str(D);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options:';
            app.LinearButton = uiradiobutton(app.OptionsButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarythmicButton = uiradiobutton(app.OptionsButtonGroup);
            app.LogarythmicButton.Text = 'Logarythmic';
            app.SemiLogarythmicXButton = uiradiobutton(app.OptionsButtonGroup);
            app.SemiLogarythmicXButton.Text = 'SemiLogarythmicX';
            app.SemiLogarythmicYButton = uiradiobutton(app.OptionsButtonGroup);
            app.SemiLogarythmicYButton.Text = 'SemiLogarythmicY';
            app.AuthorWojciechZubLabel = uilabel(app.UIFigure);
            app.AuthorWojciechZubLabel.Text = 'Author: Wojciech Zub';
            app.PolynomialEditField = uieditfield(app.UIFigure, 'text');
            app.PolynomialEditFieldLabel = uilabel(app.UIFigure);
            app.PolynomialEditFieldLabel.Text = 'Polynomial';
            app.VminEditFieldLabel = uilabel(app.UIFigure);
            app.VminEditFieldLabel.Text = 'Vmin';
            app.VminEditField = uieditfield(app.UIFigure, 'text');
            app.VmaxEditFieldLabel = uilabel(app.UIFigure);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.VmaxEditField = uieditfield(app.UIFigure, 'text');
            app.DRAWButton = uibutton(app.UIFigure, 'push');
            app.DRAWButton.ButtonPushedFcn = createCallbackFcn(app, @ploting, true);
            app.DRAWButton.Text = 'DRAW';
            app.HELPButton = uibutton(app.UIFigure, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Text = 'HELP';
            app.GRIDONOFFButton = uibutton(app.UIFigure, 'push');
            app.GRIDONOFFButton.ButtonPushedFcn = createCallbackFcn(app, @GRIDONOFFButtonPushed, true);
            app.GRIDONOFFButton.Text = 'GRID ON/OFF';
            app.DerivativeorderEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeorderEditFieldLabel.Text = 'Derivative order';
            app.DerivativeorderEditField = uieditfield(app.UIFigure, 'text');
            app.CALCULATEDERIVATIVEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEDERIVATIVEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEDERIVATIVEButtonPushed, true);
            app.CALCULATEDERIVATIVEButton.Text = 'CALCULATE DERIVATIVE';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyApp_P

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end